classdef acousticTesterFVNdsp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FVNBasicMeasurementTool      matlab.ui.Figure
        STOPButton                   matlab.ui.control.Button
        QUITButton                   matlab.ui.control.Button
        UILevelAxes                  matlab.ui.control.UIAxes
        UIShortRespAxis              matlab.ui.control.UIAxes
        UILongRespAxis               matlab.ui.control.UIAxes
        UIGainAxis                   matlab.ui.control.UIAxes
        MEASURELButton               matlab.ui.control.Button
        RESTARTButton                matlab.ui.control.Button
        UILevelMonAxes               matlab.ui.control.UIAxes
        TransferfunctionButtonGroup  matlab.ui.container.ButtonGroup
        RawButton                    matlab.ui.control.RadioButton
        octButton13                  matlab.ui.control.RadioButton
        octButton16                  matlab.ui.control.RadioButton
        MEASURERButton               matlab.ui.control.Button
        CALdBDropDownLabel           matlab.ui.control.Label
        CALdBDropDown                matlab.ui.control.DropDown
        FVNAcousticsToolLabel        matlab.ui.control.Label
        AboutButton                  matlab.ui.control.Button
        Voicememo5sButton            matlab.ui.control.Button
        VoiceMemoCounterLabel        matlab.ui.control.Label
        SYNCDropDownLabel            matlab.ui.control.Label
        SYNCDropDown                 matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        Property % Description
        FVNToolBody % common property
        LevelMonitor % Level monitor
        MonitorMonitor % Monitor Level monitor
        shortRespMonitor % short response viewer
        longRespMonitor % long whole response viewer
        gainMonitor % gain function viewer
        CommonSignal % common signal property and data
        IOdevice % IO devices
        memoData % voice memo
    end
    
    methods (Access = private)
        
        function app = levelMonitorSetup(app)
            app.LevelMonitor.bar = bar(app.UILevelAxes, 0.5, -11, ...
                'BaseValue', -100);
            app.LevelMonitor.peak = line(app.UILevelAxes, [0 1], -6 * [1 1], ...
                'linewidth', 3, 'color', 'r');
            axis(app.UILevelAxes, [0 1 -100 0]);
            app.LevelMonitor.calibrationSPL = 0; % dB
            app.LevelMonitor.dBbiasToSPL = 0; % dB
            app.LevelMonitor.calibrationDistance = 0; % cm
            app.LevelMonitor.weightFilt = weightingFilter;
            set(app.UILevelAxes, 'xtick', [], 'ygrid', 'on')
            app.UILevelMonAxes.Toolbar.Visible = 'off';
            app.UILevelAxes.Toolbar.Visible = 'off';
            disableDefaultInteractivity(app.UILevelAxes);
            disableDefaultInteractivity(app.UILevelMonAxes);
            drawnow
        end
        
        function updateLoop(app)
            fs = app.CommonSignal.samplingFrequency;
            sampleL = app.CommonSignal.samplePerFrame;
            app.IOdevice.maxChannels = 2;
            app.IOdevice.audioReader = audioDeviceReader(fs, sampleL,"NumChannels", 2);
            dviceInfo = info(app.IOdevice.audioReader);
            app.IOdevice.maxChannels = dviceInfo.MaximumInputChannels;
            if app.IOdevice.maxChannels < 2
                app.IOdevice.audioReader = audioDeviceReader(fs, sampleL,"NumChannels", 1);
                app.SYNCDropDown.Value = 'Internal';
                app.SYNCDropDown.Enable = 'off';
            end
            fprintf(app.FVNToolBody.fileID, '%s\n',  num2str((now - app.FVNToolBody.startTime) * 86400, '%15.3f') ...
                + " audio_loop_started " + num2str(app.IOdevice.maxChannels) + " channels");
            switch computer
                case 'PCWIN64'
                    app.IOdevice.audioWriter = ...
                        audioDeviceWriter(fs, 'BufferSize', sampleL);
            end
            app.IOdevice.running = 1;
            indVector = 1:sampleL;
            loopLength = length(app.CommonSignal.loopBuffer1);
            loopBuffer = zeros(loopLength, 1);
            ii = 1;
            while ii + sampleL < loopLength
                yy = app.IOdevice.audioReader();
                if app.IOdevice.maxChannels > 1
                    loopBuffer(max(1, ii:ii+sampleL - 1)) = yy(:, 2);
                end
                ii = ii + sampleL;
            end
            switch app.IOdevice.synchSource
                case {'Internal','Internal2'}
                    loopBuffer = (app.CommonSignal.loopBuffer1 ...
                        + app.CommonSignal.loopBuffer2) / 2;
            end
            tmpResp1 = fftfilt(loopBuffer, app.CommonSignal.revrseFvn1);
            tmpResp2 = fftfilt(loopBuffer, app.CommonSignal.revrseFvn2);
            app.CommonSignal.thresholdEvt = max(tmpResp1 + tmpResp2);
            fftl = app.CommonSignal.fftl;
            nto = app.CommonSignal.nto;
            app.CommonSignal.recBuffer = zeros(fftl * 2, 1);
            app.CommonSignal.recBuffer2 = zeros(fftl * 2, 1);
            deconvBuffer1 = zeros(fftl * 2, 1);
            deconvBuffer2 = zeros(fftl * 2, 1);
            deconvBuffer3 = zeros(fftl * 2, 1);
            deconvBuffer4 = zeros(fftl * 2, 1);
            respYdata = get(app.longRespMonitor.channelL, 'ydata');
            dispIdx = (1:length(respYdata));
            respIdx = dispIdx;%(1:1.9*nto);
            impulseResp1 = respIdx(:) * 0;
            impulseResp2 = respIdx(:) * 0;
            impulseResp3 = respIdx(:) * 0;
            impulseResp4 = respIdx(:) * 0;
            biasSeq2 = [1 -1 1 -1];
            biasSeq3 = [1  1 -1 -1];
            preIdx = 1:fftl * 2 - sampleL;
            postIdx = 1+sampleL:fftl*2;
            clearIdx = fftl * 2 - sampleL + 1:fftl*2;
            olaLength = length(app.CommonSignal.revrseFvn1);
            olaIndex = (1:olaLength) + fftl * 2 - olaLength;
            dispBias = 0;
            while app.IOdevice.running
                localIDX = rem(indVector, loopLength) + 1;
                deconvBuffer1(preIdx) = deconvBuffer1(postIdx);
                deconvBuffer2(preIdx) = deconvBuffer2(postIdx);
                deconvBuffer3(preIdx) = deconvBuffer3(postIdx);
                deconvBuffer4(preIdx) = deconvBuffer4(postIdx);
                deconvBuffer1(clearIdx) = 0;
                deconvBuffer2(clearIdx) = 0;
                deconvBuffer3(clearIdx) = 0;
                deconvBuffer4(clearIdx) = 0;
                app.CommonSignal.recBuffer(preIdx) = ...
                    app.CommonSignal.recBuffer(postIdx);
                switch computer
                    case 'PCWIN64'
                        sampleNoise = [app.CommonSignal.loopBuffer1(localIDX) ...
                            app.CommonSignal.loopBuffer2(localIDX)];
                        app.IOdevice.audioWriter(sampleNoise);
                end
                yin = app.IOdevice.audioReader();
                y = yin(:, 1);
                if app.IOdevice.maxChannels == 1
                    yy = [y, (app.CommonSignal.loopBuffer1(localIDX) ...
                        + app.CommonSignal.loopBuffer2(localIDX)) / 2, ...
                        (app.CommonSignal.loopBuffer1(localIDX) ...
                        + app.CommonSignal.loopBuffer2(localIDX)) / 2];
                else
                    yy = [yin, (app.CommonSignal.loopBuffer1(localIDX) ...
                        + app.CommonSignal.loopBuffer2(localIDX)) / 2];
                end
                app.CommonSignal.recBuffer((1:sampleL) + fftl * 2 - sampleL) = y;
                tmpResp1 = fftfilt(yy, app.CommonSignal.revrseFvn1);
                tmpResp2 = fftfilt(yy, app.CommonSignal.revrseFvn2);
                resp3 = fftfilt(y, app.CommonSignal.revrseFvn3);
                resp1 = tmpResp1(:, 1);
                resp2 = tmpResp2(:, 1);
                switch app.IOdevice.synchSource
                    case {'Internal','Internal2'}
                        resp4 = tmpResp2(:, 3) + tmpResp1(:, 3);
                        switch app.IOdevice.synchSource
                            case 'Internal2'
                                app.CommonSignal.recBuffer2(preIdx) = ...
                    app.CommonSignal.recBuffer2(postIdx);
                app.CommonSignal.recBuffer2((1:sampleL) + fftl * 2 - sampleL) = yin(:, 2);
                        end
                    case 'R-Ch.'
                        resp4 = tmpResp2(:, 2) + tmpResp1(:, 2);
                end
                deconvBuffer1(olaIndex) = deconvBuffer1(olaIndex) + resp1;
                deconvBuffer2(olaIndex) = deconvBuffer2(olaIndex) + resp2;
                deconvBuffer3(olaIndex) = deconvBuffer3(olaIndex) + resp3;
                deconvBuffer4(olaIndex) = deconvBuffer4(olaIndex) + resp4;
                peakAmplitude = 20 * log10(max(abs(y)));
                rmsAmplitude = 20 * log10(std(y));
                peakMonAmplitude = 20 * log10(max(abs(yy(:,2))));
                rmsMonAmplitude = 20 * log10(std(yy(:, 2)));
                sel = (1:round(sampleL * 3)) + fftl * 2 - 14 * sampleL;
                if max(deconvBuffer4(sel)) > app.CommonSignal.thresholdEvt * 0.8
                    [~, maxIdx] = max(deconvBuffer4(sel));
                    impulseResp1 = impulseResp1 * 0;
                    impulseResp2 = impulseResp2 * 0;
                    impulseResp3 = impulseResp3 * 0;
                    impulseResp4 = impulseResp4 * 0;
                    baseIdx = sel(1) + maxIdx - 1 - 100 + dispBias;
                    for jj = 1:4
                        impulseResp1 = impulseResp1 + ...
                            deconvBuffer1(respIdx + (jj - 1) * nto + baseIdx);
                        impulseResp2 = impulseResp2 + ...
                            deconvBuffer2(respIdx + (jj - 1) * nto + baseIdx) * biasSeq2(jj);
                        impulseResp3 = impulseResp3 + ...
                            deconvBuffer3(respIdx + (jj - 1) * nto + baseIdx) * biasSeq3(jj);
                        impulseResp4 = impulseResp4 + ...
                            deconvBuffer4(respIdx + (jj - 1) * nto + baseIdx) * biasSeq2(jj);
                    end
                    impulseResp1 = impulseResp1 - mean(impulseResp1);
                    impulseResp2 = impulseResp2 - mean(impulseResp2);
                    impulseResp3 = impulseResp3 - mean(impulseResp3);
                    impulseResp4 = impulseResp4 - mean(impulseResp4);
                    dispSel = dispIdx;
                    fftlDisp = app.gainMonitor.fftlDisp;
                    cf = 1 / sqrt(std(impulseResp1(dispSel))^2 + std(impulseResp2(dispSel))^2) / fftlDisp * 100;
                    switch app.shortRespMonitor.text
                        case 'Raw'
                            gain1 = 20*log10(abs(fft(impulseResp1(dispSel) *cf, fftlDisp)));
                            gain2 = 20*log10(abs(fft(impulseResp2(dispSel) *cf, fftlDisp)));
                            gain3 = 20*log10(abs(fft(impulseResp3(dispSel) *cf, fftlDisp)));
                        case '1/3 oct.'
                            cumPw1 = cumsum(abs(fft(impulseResp1(dispSel) *cf, fftlDisp)) .^ 2);
                            cumPw2 = cumsum(abs(fft(impulseResp2(dispSel) *cf, fftlDisp)) .^ 2);
                            cumPw3 = cumsum(abs(fft(impulseResp3(dispSel) *cf, fftlDisp)) .^ 2);
                            tmpPw1H = interp1(app.gainMonitor.fx, cumPw1, ...
                                app.gainMonitor.fxH3, 'linear', 'extrap');
                            tmpPw1L = interp1(app.gainMonitor.fx, cumPw1, ...
                                app.gainMonitor.fxL3, 'linear', 'extrap');
                            gain1 = 10 * log10((tmpPw1H - tmpPw1L) ./ app.gainMonitor.bw3);
                            tmpPw2H = interp1(app.gainMonitor.fx, cumPw2, ...
                                app.gainMonitor.fxH3, 'linear', 'extrap');
                            tmpPw2L = interp1(app.gainMonitor.fx, cumPw2, ...
                                app.gainMonitor.fxL3, 'linear', 'extrap');
                            gain2 = 10 * log10((tmpPw2H - tmpPw2L) ./ app.gainMonitor.bw3);
                            tmpPw3H = interp1(app.gainMonitor.fx, cumPw3, ...
                                app.gainMonitor.fxH3, 'linear', 'extrap');
                            tmpPw3L = interp1(app.gainMonitor.fx, cumPw3, ...
                                app.gainMonitor.fxL3, 'linear', 'extrap');
                            gain3 = 10 * log10((tmpPw3H - tmpPw3L) ./ app.gainMonitor.bw3);
                        case '1/6 oct.'
                            cumPw1 = cumsum(abs(fft(impulseResp1(dispSel) *cf, fftlDisp)) .^ 2);
                            cumPw2 = cumsum(abs(fft(impulseResp2(dispSel) *cf, fftlDisp)) .^ 2);
                            cumPw3 = cumsum(abs(fft(impulseResp3(dispSel) *cf, fftlDisp)) .^ 2);
                            tmpPw1H = interp1(app.gainMonitor.fx, cumPw1, ...
                                app.gainMonitor.fxH6, 'linear', 'extrap');
                            tmpPw1L = interp1(app.gainMonitor.fx, cumPw1, ...
                                app.gainMonitor.fxL6, 'linear', 'extrap');
                            gain1 = 10 * log10((tmpPw1H - tmpPw1L) ./ app.gainMonitor.bw6);
                            tmpPw2H = interp1(app.gainMonitor.fx, cumPw2, ...
                                app.gainMonitor.fxH6, 'linear', 'extrap');
                            tmpPw2L = interp1(app.gainMonitor.fx, cumPw2, ...
                                app.gainMonitor.fxL6, 'linear', 'extrap');
                            gain2 = 10 * log10((tmpPw2H - tmpPw2L) ./ app.gainMonitor.bw6);
                            tmpPw3H = interp1(app.gainMonitor.fx, cumPw3, ...
                                app.gainMonitor.fxH6, 'linear', 'extrap');
                            tmpPw3L = interp1(app.gainMonitor.fx, cumPw3, ...
                                app.gainMonitor.fxL6, 'linear', 'extrap');
                            gain3 = 10 * log10((tmpPw3H - tmpPw3L) ./ app.gainMonitor.bw6);
                    end
                    set(app.gainMonitor.channelL, 'ydata', gain1);
                    set(app.gainMonitor.channelR, 'ydata', gain2);
                    set(app.gainMonitor.channelC, 'ydata', gain3);
                    reverseResp1 = abs(fftfilt(ones(22, 1), abs(impulseResp1(dispSel)) .^ 2));
                    reverseResp2 = abs(fftfilt(ones(22, 1), abs(impulseResp2(dispSel)) .^ 2));
                    set(app.longRespMonitor.channelL, 'ydata', ...
                        10*log10(reverseResp1 / max(reverseResp1)));
                    set(app.longRespMonitor.channelR, 'ydata', ...
                        10*log10(reverseResp2 / max(reverseResp2)));
                    set(app.UILongRespAxis, 'ylim', [-70 0]);
                    tmp1 = impulseResp1(dispSel(1:sampleL));
                    tmp1 = tmp1 / max(abs(tmp1));
                    set(app.shortRespMonitor.channelL, 'ydata', tmp1);
                    tmp2 = impulseResp2(dispSel(1:sampleL));
                    tmp2 = tmp2 / max(abs(tmp2));
                    set(app.shortRespMonitor.channelR, 'ydata', tmp2);
                    switch app.IOdevice.synchSource
                        case {'Internal','Internal2'}
                            if dispBias == 0
                                [~, peakLoc] = max(reverseResp1 + reverseResp2);
                                dispBias = peakLoc - 200;
                            end
                    end
                end
                set(app.LevelMonitor.peak, 'ydata', peakAmplitude * [1 1]);
                set(app.LevelMonitor.bar, 'ydata', rmsAmplitude);
                set(app.MonitorMonitor.peak, 'ydata', peakMonAmplitude * [1 1]);
                set(app.MonitorMonitor.bar, 'ydata', rmsMonAmplitude);
                drawnow limitrate
                indVector = indVector + sampleL;
            end
            app.IOdevice.running = 0;
            set(app.QUITButton, 'enable', 'on');
            set(app.MEASURELButton, 'enable', 'on');
            set(app.MEASURERButton, 'enable', 'on');
            set(app.RESTARTButton, 'enable', 'on');
            set(app.STOPButton, 'enable', 'off');
            release(app.IOdevice.audioReader);
            switch computer
                case 'PCWIN64'
                    release(app.IOdevice.audioWriter);
            end
            pause(0.5);
        end
        
        function app = initializeSignals(app)
            app.CommonSignal.samplingFrequency = 44100;
            app.CommonSignal.samplePerFrame = 4410 * 2;
            app.CommonSignal.responseLength = 0.2; % second
        end
        
        function app = initializeFVNtestsignals(app)
            nto = round(app.CommonSignal.responseLength * ...
                app.CommonSignal.samplingFrequency);
            app.CommonSignal.nto = nto;
            tmp = load('fvnMin200ms.mat');
            tmpPink = load('basicFVNparamsFor44k.mat');
            a = tmpPink.a;
            app.CommonSignal.a = a;
            
            fvnSet = tmp.fvnMin200ms;
            fftlOrg = size(fvnSet, 1);
            fftl = fftlOrg / 2;
            fvn1 = fvnSet((1:fftl) + fftl / 2, 1);
            fvn2 = fvnSet((1:fftl) + fftl / 2, 2);
            fvn3 = fvnSet((1:fftl) + fftl / 2, 3);
            app.CommonSignal.fftl = fftl;
            buffer1white = zeros(fftl * 10, 1);
            buffer2white = zeros(fftl * 10, 1);
            nData = length(buffer1white);
            location = 0;
            pol1 = 1;
            pol2 = 1;
            while location + fftl < nData
                buffer1white((1:fftl) + location) = ...
                    buffer1white((1:fftl) + location) + fvn1;
                buffer2white((1:fftl) + location) = ...
                    buffer2white((1:fftl) + location) + pol1 * fvn2;
                pol1 = -1 * pol1;
                if pol1 > 0
                    pol2 = -1 * pol2;
                end
                location = location + nto;
            end
            buffer1 = filter(1, a, buffer1white);
            buffer2 = filter(1, a, buffer2white);
            selector = round(nData / 2) + (1:12 * nto);
            levelFixer = 0.8 / max(abs(buffer1(:)));
            app.CommonSignal.loopBuffer1 = buffer1(selector) * levelFixer;
            app.CommonSignal.loopBuffer2 = buffer2(selector) * levelFixer;
            rp = 4.2;%3.65;%4.8; % based on simulation
            app.CommonSignal.revrseFvn1 = ...
                filter(a, 1, fvn1(fftl / 2 + (round(rp*nto):-1:-round(rp*nto))));
            app.CommonSignal.revrseFvn2 = ...
                filter(a, 1,  fvn2(fftl / 2 + (round(rp*nto):-1:-round(rp*nto))));
            app.CommonSignal.revrseFvn3 = ...
                filter(a, 1,  fvn3(fftl / 2 + (round(rp*nto):-1:-round(rp*nto))));
            switch computer
                case 'MACI64'
                    [x, fss] = audioread("fvnTestSigForRtTool.wav");
                    app.Property.testSignalDuration = length(x) / fss;
                    audiowrite(app.FVNToolBody.outPath + "/" + "fvnTestSigForRtTool.wav", ...
                        x, fss, "BitsPerSample", 24);
                    app.Property.afsCommand = "afplay " + app.FVNToolBody.outPath + "/" + "fvnTestSigForRtTool.wav &";
                    status = system(app.Property.afsCommand);
                    %status = system("afplay fvnTestSigForRtTool.wav &");
                    app.Property.afplayPID = 'A';
                    app.Property.signalStartTic = tic;
                    afpName = app.FVNToolBody.outPath + "/" + "afp.txt";
                    gggName = app.FVNToolBody.outPath + "/" + "ggg.txt";
                    counter = 0.5;
                    app.Property.gggChecker = "ps |cat > " + gggName;
                    app.Property.afpChecker = "cat " + gggName + "|grep 'afplay' |awk '{print $1}' > " + afpName;
                    while ~isempty(app.Property.afplayPID) && counter > 0
                        %system("ps |cat >ggg.txt");
                        %system("cat ggg.txt|grep 'afplay' |awk '{print $1}' > afp.txt");
                        system(app.Property.gggChecker);
                        system(app.Property.afpChecker);
                        tmp = load(afpName);
                        app.Property.afplayPID = tmp;
                        pause(0.1);
                        counter = counter - 0.1;
                    end
                    fprintf(app.FVNToolBody.fileID, '%s\n',  num2str((now - app.FVNToolBody.startTime) * 86400, '%15.3f') ...
                        + " afplay_status " + num2str(status));
            end
        end
        
        function app = shortRespSetup(app)
            tInMs = (1:app.CommonSignal.samplePerFrame) ...
                / app.CommonSignal.samplingFrequency * 1000;
            app.shortRespMonitor.channelL = ...
                plot(app.UIShortRespAxis, tInMs, ...
                app.CommonSignal.loopBuffer1(1:app.CommonSignal.samplePerFrame));
            app.shortRespMonitor.channelR = ...
                line(app.UIShortRespAxis, tInMs, ...
                app.CommonSignal.loopBuffer1(1:app.CommonSignal.samplePerFrame), ...
                'color', [0.8 0 0]);
            set(app.UIShortRespAxis, 'xlim', [0 30]);
            app.UIShortRespAxis.Toolbar.Visible = 'off';
            disableDefaultInteractivity(app.UIShortRespAxis);
        end
        
        function app = reverseIntrgrationSetup(app)
            fs = app.CommonSignal.samplingFrequency;
            respIdx = 1:round(app.CommonSignal.responseLength * fs);
            app.longRespMonitor.channelL = ...
                plot(app.UILongRespAxis, respIdx / fs, ...
                app.CommonSignal.loopBuffer1(respIdx));
            app.longRespMonitor.channelR = ...
                line(app.UILongRespAxis, respIdx / fs, ...
                app.CommonSignal.loopBuffer1(respIdx), 'color', [0.8 0 0]);
            app.longRespMonitor.text = 'Linear';
            set(app.UILongRespAxis, 'xlim', [0 respIdx(end) / fs]);
            set(app.UILongRespAxis, 'ylim', [-1 1]);
            app.UILongRespAxis.Toolbar.Visible = 'off';
            disableDefaultInteractivity(app.UILongRespAxis);
        end
        
        function app = gainPlotSetup(app)
            fs = app.CommonSignal.samplingFrequency;
            fftlDisp = 2 ^ ceil(log2(app.CommonSignal.nto + 10));
            fx = (0:fftlDisp-1)/fftlDisp * fs;
            app.gainMonitor.fx = fx;
            app.gainMonitor.fxH3 = fx * 2^(1/3);
            app.gainMonitor.fxL3 = fx * 2^(-1/3);
            app.gainMonitor.fxH6 = fx * 2^(1/6);
            app.gainMonitor.fxL6 = fx * 2^(-1/6);
            app.gainMonitor.bw3 = app.gainMonitor.fxH3 - app.gainMonitor.fxL3;
            app.gainMonitor.bw3(1) = 1;
            app.gainMonitor.bw6 = app.gainMonitor.fxH6 - app.gainMonitor.fxL6;
            app.gainMonitor.bw6(1) = 1;
            app.gainMonitor.channelL = semilogx(app.UIGainAxis, fx, ...
                fx * 0 - 1);
            app.gainMonitor.channelR = line(app.UIGainAxis, fx, ...
                fx * 0 + 1, 'color', [0.8 0 0]);
            app.gainMonitor.channelC = line(app.UIGainAxis, fx, ...
                fx * 0 + 1, 'color', [0.7 0.7 0]);
            axis(app.UIGainAxis, [20 fs/2 -70 15]);
            set(app.UIGainAxis, 'xgrid', 'on', 'ygrid', 'on');
            app.gainMonitor.fftlDisp = fftlDisp;
            app.shortRespMonitor.text = 'Raw';
            app.UIGainAxis.Toolbar.Visible = 'off';
            disableDefaultInteractivity(app.UIGainAxis);
        end
        
        function app = monitorMonitorSetup(app)
            app.MonitorMonitor.bar = bar(app.UILevelMonAxes, 0.5, -11, ...
                'BaseValue', -100);
            app.MonitorMonitor.peak = line(app.UILevelMonAxes, [0 1], -6 * [1 1], ...
                'linewidth', 3, 'color', 'r');
            axis(app.UILevelMonAxes, [0 1 -100 0]);
            set(app.UILevelMonAxes, 'xtick', [], 'ygrid', 'on')
            drawnow
        end
        
        function app = assignLogFile(app)
            outPath = uigetdir('', 'Select output file directory:');
            app.FVNToolBody.outPath = outPath;
            nameOfLog = ['fvnLogT' datestr(now, 30) '.txt'];
            app.FVNToolBody.fileID = fopen([outPath '/' nameOfLog], 'w');
            fprintf(app.FVNToolBody.fileID, 'Start: %s\n', ...
                datestr(now));
            fprintf(app.FVNToolBody.fileID,'computer: %s\n', computer);
            app.FVNToolBody.startTime = now;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app = assignLogFile(app);
            app = levelMonitorSetup(app);
            app = monitorMonitorSetup(app);
            app = initializeSignals(app);
            app = initializeFVNtestsignals(app);
            app = shortRespSetup(app);
            app = reverseIntrgrationSetup(app);
            app = gainPlotSetup(app);
            set(app.QUITButton, 'enable', 'off');
            set(app.MEASURELButton, 'enable', 'off');
            set(app.MEASURERButton, 'enable', 'off');
            set(app.RESTARTButton, 'enable', 'off');
            app.Voicememo5sButton.Enable = 'off';
            app.VoiceMemoCounterLabel.Visible = 'off';
            app.IOdevice.synchSource = 'Internal';
            updateLoop(app);
        end

        % Button pushed function: QUITButton
        function QUITButtonPushed(app, event)
            release(app.IOdevice.audioReader);
            switch computer
                case 'PCWIN64'
                    release(app.IOdevice.audioWriter);
            end
            fclose(app.FVNToolBody.fileID);
            pause(0.5);
            close(app.FVNBasicMeasurementTool);
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            switch computer
                case 'MACI64'
                    cmdString = ['!kill -9 ' num2str(app.Property.afplayPID(end))];
                    eval(cmdString);
            end
            app.IOdevice.running = 0;
            set(app.QUITButton, 'enable', 'on');
            if app.LevelMonitor.calibrationSPL > 0
                set(app.MEASURELButton, 'enable', 'on');
                set(app.MEASURERButton, 'enable', 'on');
            end
            set(app.RESTARTButton, 'enable', 'on');
            set(app.STOPButton, 'enable', 'off');
            release(app.IOdevice.audioReader);
            app.Voicememo5sButton.Enable = 'on';
            switch computer
                case 'PCWIN64'
                    release(app.IOdevice.audioWriter);
            end
            fprintf(app.FVNToolBody.fileID, '%s\n',  num2str((now - app.FVNToolBody.startTime) * 86400, '%15.3f') ...
                + " stop_button_pressed ");
            pause(0.5);
        end

        % Button pushed function: MEASURELButton
        function MEASURELButtonPushed(app, event)
            switch app.IOdevice.synchSource
                case 'Internal2'
                    dBbiasToSPL = [app.LevelMonitor.dBbiasToSPL, ...
                        app.LevelMonitor.dBbiasToSPL2];
                otherwise
                    dBbiasToSPL = app.LevelMonitor.dBbiasToSPL;
            end
            set(app.MEASURELButton, 'enable', 'off');
            set(app.MEASURERButton, 'enable', 'off');
            app.Voicememo5sButton.Enable = 'off';
            outPath = app.FVNToolBody.outPath;
            output = tripletMeasurement('L', dBbiasToSPL, outPath, ...
                app.IOdevice.maxChannels, app.IOdevice.synchSource, 1);
            formatSpec = 'Lch output file: %s\n';
            fprintf(app.FVNToolBody.fileID, formatSpec, output.recordNameBody);
            switch app.IOdevice.synchSource
                case 'Internal2'
                    formatSpec = 'Lch-2 output file: %s\n';
                    fprintf(app.FVNToolBody.fileID, formatSpec, output.output2.recordNameBody);
            end
            set(app.MEASURELButton, 'enable', 'on');
            set(app.MEASURERButton, 'enable', 'on');
            app.Voicememo5sButton.Enable = 'on';
        end

        % Button pushed function: RESTARTButton
        function RESTARTButtonPushed(app, event)
            switch computer
                case 'MACI64'
                    status = system(app.Property.afsCommand);
                    app.Property.afplayPID = 'A';
                    app.Property.signalStartTic = tic;
                    %afpName = 'afp.txt';
                    afpName = app.FVNToolBody.outPath + "/" + "afp.txt";
                    counter = 0.3;
                    while ~isempty(app.Property.afplayPID) && counter > 0
                        %system("ps |cat >ggg.txt");
                        %system("cat ggg.txt|grep 'afplay' |awk '{print $1}' > afp.txt");
                        system(app.Property.gggChecker);
                        system(app.Property.afpChecker);
                        tmp = load(afpName);
                        app.Property.afplayPID = tmp;
                        pause(0.1);
                        counter = counter - 0.1;
                    end
                    fprintf(app.FVNToolBody.fileID, '%s\n',  num2str((now - app.FVNToolBody.startTime) * 86400, '%15.3f') ...
                        + " afplay_status " + num2str(status));
            end
            app.Voicememo5sButton.Enable = 'off';
            set(app.QUITButton, 'enable', 'off');
            set(app.MEASURELButton, 'enable', 'off');
            set(app.MEASURERButton, 'enable', 'off');
            set(app.STOPButton, 'enable', 'on');
            set(app.RESTARTButton, 'enable', 'off');
            updateLoop(app);
        end

        % Selection changed function: TransferfunctionButtonGroup
        function TransferfunctionButtonGroupSelectionChanged(app, event)
            selectedButton = app.TransferfunctionButtonGroup.SelectedObject;
            app.shortRespMonitor.text = selectedButton.Text;
            switch app.shortRespMonitor.text
                case 'Raw'
                    title(app.UIGainAxis, 'Amplitude transfer function (Raw)');
                case '1/3 oct.'
                    title(app.UIGainAxis, 'Amplitude transfer function (1/3 oct.)');
                case '1/6 oct.'
                    title(app.UIGainAxis, 'Amplitude transfer function (1/6 oct.)');
            end
            fprintf(app.FVNToolBody.fileID, '%s\n',  num2str((now - app.FVNToolBody.startTime) * 86400, '%15.3f') ...
                + " spec_display_style " + string(app.shortRespMonitor.text));
        end

        % Button pushed function: MEASURERButton
        function MEASURERButtonPushed(app, event)
            switch app.IOdevice.synchSource
                case 'Internal2'
                    dBbiasToSPL = [app.LevelMonitor.dBbiasToSPL, ...
                        app.LevelMonitor.dBbiasToSPL2];
                otherwise
                    dBbiasToSPL = app.LevelMonitor.dBbiasToSPL;
            end
            set(app.MEASURELButton, 'enable', 'off');
            set(app.MEASURERButton, 'enable', 'off');
            app.Voicememo5sButton.Enable = 'off';
            outPath = app.FVNToolBody.outPath;
            output = tripletMeasurement('R', dBbiasToSPL, outPath, ...
                app.IOdevice.maxChannels, app.IOdevice.synchSource, 1);
            formatSpec = 'Rch output file: %s\n';
            fprintf(app.FVNToolBody.fileID, formatSpec, output.recordNameBody);
            switch app.IOdevice.synchSource
                case 'Internal2'
                    formatSpec = 'Lch-2 output file: %s\n';
                    fprintf(app.FVNToolBody.fileID, formatSpec, output.output2.recordNameBody);
            end
            set(app.MEASURELButton, 'enable', 'on');
            set(app.MEASURERButton, 'enable', 'on');
            app.Voicememo5sButton.Enable = 'on';
        end

        % Value changed function: CALdBDropDown
        function CALdBDropDownValueChanged(app, event)
            value = app.CALdBDropDown.Value;
            %disp(['calibration dB:' num2str(str2double(value))]);
            fftl = app.CommonSignal.fftl;
            y = app.CommonSignal.recBuffer(end-fftl:end);
            yweighted = app.LevelMonitor.weightFilt(y);
            weightedDb = 20 * log10(std(yweighted));
            app.LevelMonitor.calibrationSPL = str2double(value);
            app.LevelMonitor.dBbiasToSPL = str2double(value) - weightedDb;
            %disp(['calibration bias dB:' num2str(weightedDb)]);
            ytickLevel = get(app.UILevelAxes, 'ytick');
            ytickLabelCal = ...
                num2str(app.LevelMonitor.dBbiasToSPL + ytickLevel(:), '%4.0f');
            set(app.UILevelAxes, 'ytickLabel', ytickLabelCal);
            formatSpec = 'Cal.Level = %5.1f dB, Cal.bias %5.1f dB\n';
            fprintf(app.FVNToolBody.fileID, formatSpec, str2double(value), ...
                str2double(value) - weightedDb);
            set(app.CALdBDropDown, 'enable', 'off');
            ylabel(app.UILevelAxes, 'sound pressure level (dB re. 20\mu Pa)')
            switch app.IOdevice.synchSource
                case 'Internal2'
                    y = app.CommonSignal.recBuffer2(end-fftl:end);
                    yweighted = app.LevelMonitor.weightFilt(y);
                    weightedDb = 20 * log10(std(yweighted));
                    app.LevelMonitor.calibrationSPL2 = str2double(value);
                    app.LevelMonitor.dBbiasToSPL2 = str2double(value) - weightedDb;
                    formatSpec = 'Cal.Level2 = %5.1f dB, Cal.bias %5.1f dB\n';
                    fprintf(app.FVNToolBody.fileID, formatSpec, str2double(value), ...
                        str2double(value) - weightedDb);
            end
        end

        % Button pushed function: AboutButton
        function AboutButtonPushed(app, event)
            ltext = ['<p style="font-family:arial;"> Acoustic measurement tool based on FVN <br><br>' ...
                'Visit the following repository for resources <br><br>' ...
                'https://github.com/HidekiKawahara/FVN <br><br> LICENSE <br><br>' ...
                'Copyright 2020 Hideki Kawahara <br><br>' ...
                'Licensed under the Apache License, Version 2.0 (the "License"); <br>' ...
                'you may not use this file except in compliance with the License. <br>' ...
                'You may obtain a copy of the License at <br><br>' ...
                'http://www.apache.org/licenses/LICENSE-2.0 <br><br>' ...
                'Unless required by applicable law or agreed to in writing, software <br>' ...
                'distributed under the License is distributed on an "AS IS" BASIS, <br>' ...
                'WITHOUT WARRANTIES OR CONDITIONS OF ANY KIND, either express or implied. <br>' ...
                'See the License for the specific language governing permissions and <br>' ...
                'limitations under the License. </p>'];
            app.FVNToolBody.fig = uifigure('Position',[561 497 700 420]);
            h = uihtml(app.FVNToolBody.fig);
            h.Position = [10 10 680 400];
            h.HTMLSource = ltext;
        end

        % Button pushed function: Voicememo5sButton
        function Voicememo5sButtonPushed(app, event)
            app.Voicememo5sButton.Enable = 'off';
            app.memoData.fs = 44100;
            app.memoData.audioRecorder = audiorecorder(app.memoData.fs, 24, 1);
            tDuration = 5;
            app.VoiceMemoCounterLabel.Visible = 'on';
            record(app.memoData.audioRecorder);
            for ii = tDuration:-0.1:0
                app.VoiceMemoCounterLabel.Text = num2str(ii);
                pause(0.1);
            end
            y = getaudiodata(app.memoData.audioRecorder);
            stop(app.memoData.audioRecorder);
            app.VoiceMemoCounterLabel.Visible = 'off';
            memoName = "memo" + datestr(now, 30) + ".wav";
            audiowrite(app.FVNToolBody.outPath + "/" + memoName, y, app.memoData.fs, "BitsPerSample", 24);
            fprintf(app.FVNToolBody.fileID, '%s\n',  num2str((now - app.FVNToolBody.startTime) * 86400, '%15.3f') ...
                + " memo_recorded " + memoName);
            app.Voicememo5sButton.Enable = 'on';
        end

        % Value changed function: SYNCDropDown
        function SYNCDropDownValueChanged(app, event)
            %fs = app.CommonSignal.samplingFrequency;
            %sampleL = app.CommonSignal.samplePerFrame;
            app.IOdevice.synchSource = app.SYNCDropDown.Value;
            switch app.IOdevice.synchSource
                case {'Internal','Internal2'}
                    loopBuffer = (app.CommonSignal.loopBuffer1 ...
                        + app.CommonSignal.loopBuffer2) / 2;
                    tmpResp1 = fftfilt(loopBuffer, app.CommonSignal.revrseFvn1);
                    tmpResp2 = fftfilt(loopBuffer, app.CommonSignal.revrseFvn2);
                    app.CommonSignal.thresholdEvt = max(tmpResp1 + tmpResp2);
                    switch app.IOdevice.synchSource
                        case 'Internal2'
                            app.CALdBDropDown.Value = '0';
                            set(app.CALdBDropDown, 'enable', 'on');
                    end
            end
            fprintf(app.FVNToolBody.fileID, '%s\n',  num2str((now - app.FVNToolBody.startTime) * 86400, '%15.3f') ...
                + " synch_mode_change " + string(app.IOdevice.synchSource));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FVNBasicMeasurementTool = uifigure('Visible', 'off');
            app.FVNBasicMeasurementTool.Name = 'UI Figure';
            app.STOPButton = uibutton(app.FVNBasicMeasurementTool, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.QUITButton = uibutton(app.FVNBasicMeasurementTool, 'push');
            app.QUITButton.ButtonPushedFcn = createCallbackFcn(app, @QUITButtonPushed, true);
            app.QUITButton.Text = 'QUIT';
            app.UILevelAxes = uiaxes(app.FVNBasicMeasurementTool);
            title(app.UILevelAxes, 'Level')
            xlabel(app.UILevelAxes, '')
            ylabel(app.UILevelAxes, 'Level (rel. MSB)')
            app.UILevelAxes.XTick = [];
            app.UIShortRespAxis = uiaxes(app.FVNBasicMeasurementTool);
            title(app.UIShortRespAxis, 'Short impulse response')
            xlabel(app.UIShortRespAxis, 'time (ms)')
            ylabel(app.UIShortRespAxis, 'Amplitide')
            app.UILongRespAxis = uiaxes(app.FVNBasicMeasurementTool);
            title(app.UILongRespAxis, 'Smoothed response power')
            xlabel(app.UILongRespAxis, 'time (s)')
            ylabel(app.UILongRespAxis, 'Level (rel. dB)')
            app.UIGainAxis = uiaxes(app.FVNBasicMeasurementTool);
            title(app.UIGainAxis, 'Amplitude transfer function (Raw)')
            xlabel(app.UIGainAxis, 'frequency (Hz)')
            ylabel(app.UIGainAxis, 'Gain (rel. dB)')
            app.MEASURELButton = uibutton(app.FVNBasicMeasurementTool, 'push');
            app.MEASURELButton.ButtonPushedFcn = createCallbackFcn(app, @MEASURELButtonPushed, true);
            app.MEASURELButton.Text = 'MEASURE-L';
            app.RESTARTButton = uibutton(app.FVNBasicMeasurementTool, 'push');
            app.RESTARTButton.ButtonPushedFcn = createCallbackFcn(app, @RESTARTButtonPushed, true);
            app.RESTARTButton.Text = 'RE-START';
            app.UILevelMonAxes = uiaxes(app.FVNBasicMeasurementTool);
            title(app.UILevelMonAxes, 'Ref.')
            xlabel(app.UILevelMonAxes, '')
            ylabel(app.UILevelMonAxes, 'Level (rel. MSB)')
            app.UILevelMonAxes.XTick = [];
            app.TransferfunctionButtonGroup = uibuttongroup(app.FVNBasicMeasurementTool);
            app.TransferfunctionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TransferfunctionButtonGroupSelectionChanged, true);
            app.TransferfunctionButtonGroup.Title = 'Transfer function';
            app.RawButton = uiradiobutton(app.TransferfunctionButtonGroup);
            app.RawButton.Text = 'Raw';
            app.RawButton.Value = true;
            app.octButton13 = uiradiobutton(app.TransferfunctionButtonGroup);
            app.octButton13.Text = '1/3 oct.';
            app.octButton16 = uiradiobutton(app.TransferfunctionButtonGroup);
            app.octButton16.Text = '1/6 oct.';
            app.MEASURERButton = uibutton(app.FVNBasicMeasurementTool, 'push');
            app.MEASURERButton.ButtonPushedFcn = createCallbackFcn(app, @MEASURERButtonPushed, true);
            app.MEASURERButton.Text = 'MEASURE-R';
            app.CALdBDropDownLabel = uilabel(app.FVNBasicMeasurementTool);
            app.CALdBDropDownLabel.Text = 'CAL.dB';
            app.CALdBDropDown = uidropdown(app.FVNBasicMeasurementTool);
            app.CALdBDropDown.Items = {'90', '80', '70', '60', '0'};
            app.CALdBDropDown.ValueChangedFcn = createCallbackFcn(app, @CALdBDropDownValueChanged, true);
            app.CALdBDropDown.Value = '0';
            app.FVNAcousticsToolLabel = uilabel(app.FVNBasicMeasurementTool);
            app.FVNAcousticsToolLabel.Text = 'FVN Acoustics Tool';
            app.AboutButton = uibutton(app.FVNBasicMeasurementTool, 'push');
            app.AboutButton.ButtonPushedFcn = createCallbackFcn(app, @AboutButtonPushed, true);
            app.AboutButton.Text = 'About';
            app.Voicememo5sButton = uibutton(app.FVNBasicMeasurementTool, 'push');
            app.Voicememo5sButton.ButtonPushedFcn = createCallbackFcn(app, @Voicememo5sButtonPushed, true);
            app.Voicememo5sButton.Text = 'Voice memo 5 s';
            app.VoiceMemoCounterLabel = uilabel(app.FVNBasicMeasurementTool);
            app.VoiceMemoCounterLabel.Text = '5.0';
            app.SYNCDropDownLabel = uilabel(app.FVNBasicMeasurementTool);
            app.SYNCDropDownLabel.Text = 'SYNC.';
            app.SYNCDropDown = uidropdown(app.FVNBasicMeasurementTool);
            app.SYNCDropDown.Items = {'Internal', 'Internal2', 'R-Ch.'};
            app.SYNCDropDown.ValueChangedFcn = createCallbackFcn(app, @SYNCDropDownValueChanged, true);
            app.SYNCDropDown.Value = 'Internal';
            % Show the figure after all components are created
            app.FVNBasicMeasurementTool.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = acousticTesterFVNdsp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FVNBasicMeasurementTool)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FVNBasicMeasurementTool)
        end
    end
end