classdef rtFVNRespToolPinkEx < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FVNBasicMeasurementTool      matlab.ui.Figure
        STOPButton                   matlab.ui.control.Button
        QUITButton                   matlab.ui.control.Button
        UILevelAxes                  matlab.ui.control.UIAxes
        UIShortRespAxis              matlab.ui.control.UIAxes
        UILongRespAxis               matlab.ui.control.UIAxes
        UIGainAxis                   matlab.ui.control.UIAxes
        MEASURELButton               matlab.ui.control.Button
        RESTARTButton                matlab.ui.control.Button
        UILevelMonAxes               matlab.ui.control.UIAxes
        WholeresponseButtonGroup     matlab.ui.container.ButtonGroup
        LinearButton                 matlab.ui.control.RadioButton
        dBButton                     matlab.ui.control.RadioButton
        TransferfunctionButtonGroup  matlab.ui.container.ButtonGroup
        RawButton                    matlab.ui.control.RadioButton
        octButton13                  matlab.ui.control.RadioButton
        octButton16                  matlab.ui.control.RadioButton
    end

    
    properties (Access = private)
        Property % Description
        LevelMonitor % Level monitor
        MonitorMonitor % Monitor Level monitor
        shortRespMonitor % short response viewer
        longRespMonitor % long whole response viewer
        gainMonitor % gain function viewer
        CommonSignal % common signal property and data
        IOdevice % IO devices
    end
    
    methods (Access = private)
        
        function app = levelMonitorSetup(app)
            app.LevelMonitor.bar = bar(app.UILevelAxes, 0.5, -11, ...
                'BaseValue', -100);
            app.LevelMonitor.peak = line(app.UILevelAxes, [0 1], -6 * [1 1], ...
                'linewidth', 3, 'color', 'r');
            axis(app.UILevelAxes, [0 1 -100 0]);
            set(app.UILevelAxes, 'xtick', [], 'ygrid', 'on')
            drawnow
        end
        
        function updateLoop(app)
            fs = app.CommonSignal.samplingFrequency;
            sampleL = app.CommonSignal.samplePerFrame;
            app.IOdevice.audioReader = audioDeviceReader(fs, sampleL);
            app.IOdevice.audioWriter = ...
                audioDeviceWriter(fs, 'BufferSize', sampleL);
            app.IOdevice.running = 1;
            indVector = 1:sampleL;
            loopLength = length(app.CommonSignal.loopBuffer1);
            thresholdEvt = 0;
            ii = 1;
            while ii + sampleL < loopLength
                thresholdTestSignal = fftfilt(app.CommonSignal.loopBuffer2(ii + indVector), ...
                    app.CommonSignal.revrseFvn2Short);
                tmpMax = max(abs(thresholdTestSignal));
                if thresholdEvt < tmpMax
                    thresholdEvt = tmpMax;
                end
                ii = ii + sampleL;
            end
            %thresholdEvt
            fftl = app.CommonSignal.fftl;
            nto = app.CommonSignal.nto;
            recBuffer = zeros(fftl * 2, 1);
            deconvBuffer1 = zeros(fftl * 2, 1);
            deconvBuffer2 = zeros(fftl * 2, 1);
            deconvBuffer3 = zeros(fftl * 2, 1);
            respYdata = get(app.longRespMonitor.channelL, 'ydata');
            dispIdx = (1:length(respYdata));
            respIdx = (1:2.4*nto);
            biasSeq2 = [1 -1 1 -1];
            biasSeq3 = [1  1 -1 -1];
            preIdx = 1:fftl * 2 - sampleL;
            postIdx = 1+sampleL:fftl*2;
            olaLength = length(app.CommonSignal.revrseFvn1);
            olaIndex = (1:olaLength) + fftl * 2 - olaLength;
            while app.IOdevice.running
                localIDX = rem(indVector, loopLength) + 1;
                deconvBuffer1(preIdx) = deconvBuffer1(postIdx);
                deconvBuffer2(preIdx) = deconvBuffer2(postIdx);
                deconvBuffer3(preIdx) = deconvBuffer3(postIdx);
                recBuffer(preIdx) = recBuffer(postIdx);
                sampleNoise = [app.CommonSignal.loopBuffer1(localIDX) ...
                    app.CommonSignal.loopBuffer2(localIDX)];
                app.IOdevice.audioWriter(sampleNoise);
                y = app.IOdevice.audioReader();
                recBuffer(1:sampleL) = y;
                resp1 = fftfilt(y, app.CommonSignal.revrseFvn1);
                resp2 = fftfilt(y, app.CommonSignal.revrseFvn2);
                resp3 = fftfilt(y, app.CommonSignal.revrseFvn3);
                deconvBuffer1(olaIndex) = deconvBuffer1(olaIndex) + resp1;
                deconvBuffer2(olaIndex) = deconvBuffer2(olaIndex) + resp2;
                deconvBuffer3(olaIndex) = deconvBuffer3(olaIndex) + resp3;
                peakAmplitude = 20 * log10(max(abs(y)));
                rmsAmplitude = 20 * log10(std(y));
                peakMonAmplitude = 20 * log10(max(abs(sampleNoise(:,2))));
                rmsMonAmplitude = 20 * log10(std(sampleNoise(:, 2)));
                tmpResp = fftfilt(app.CommonSignal.loopBuffer2(localIDX), ...
                    app.CommonSignal.revrseFvn2Short);
                sel = round(length(tmpResp) / 2) + (-1024:1023);
                if max(abs(tmpResp(sel))) > thresholdEvt * 0.8
                    %set(app.shortRespMonitor.channelL, 'ydata', ...
                    %    y);
                    [~, maxIdx] = max(abs(tmpResp(sel)));
                    maxValue = tmpResp(sel(maxIdx));
                    impulseResp1 = respIdx(:) * 0;
                    impulseResp2 = respIdx(:) * 0;
                    impulseResp3 = respIdx(:) * 0;
                    baseIdx = fftl * 2 - olaLength - respIdx(end) + maxIdx;
                    for jj = 1:4
                        impulseResp1 = impulseResp1 + ...
                            deconvBuffer1(respIdx - jj * nto + baseIdx);
                        impulseResp2 = impulseResp2 + ...
                            deconvBuffer2(respIdx - jj * nto + baseIdx) * biasSeq2(jj);
                        impulseResp3 = impulseResp3 + ...
                            deconvBuffer3(respIdx - jj * nto + baseIdx) * biasSeq3(jj);
                    end
                    impulseResp2 = impulseResp2 * sign(maxValue);
                    [~, maxIdx] = max(abs(impulseResp1));
                    candIdx = maxIdx + (-3*nto:nto:3*nto);
                    [~, minPos] = min(abs(length(impulseResp1) / 2 - candIdx));
                    if candIdx(minPos) < length(impulseResp1) / 2
                        dispSel = dispIdx + candIdx(minPos) - 100;
                    else
                        dispSel = dispIdx + candIdx(minPos) - nto -100;
                    end
                    fftlDisp = app.gainMonitor.fftlDisp;
                    cf = 1 / std(impulseResp1(dispSel)) / fftlDisp * 100;
                    switch app.shortRespMonitor.text
                        case 'Raw'
                            gain1 = 20*log10(abs(fft(impulseResp1(dispSel) *cf, fftlDisp)));
                            gain2 = 20*log10(abs(fft(impulseResp2(dispSel) *cf, fftlDisp)));
                            gain3 = 20*log10(abs(fft(impulseResp3(dispSel) *cf, fftlDisp)));
                        case '1/3 oct.'
                            cumPw1 = cumsum(abs(fft(impulseResp1(dispSel) *cf, fftlDisp)) .^ 2);
                            cumPw2 = cumsum(abs(fft(impulseResp2(dispSel) *cf, fftlDisp)) .^ 2);
                            cumPw3 = cumsum(abs(fft(impulseResp3(dispSel) *cf, fftlDisp)) .^ 2);
                            tmpPw1H = interp1(app.gainMonitor.fx, cumPw1, ...
                                app.gainMonitor.fxH3, 'linear', 'extrap');
                            tmpPw1L = interp1(app.gainMonitor.fx, cumPw1, ...
                                app.gainMonitor.fxL3, 'linear', 'extrap');
                            gain1 = 10 * log10((tmpPw1H - tmpPw1L) ./ app.gainMonitor.bw3);
                            tmpPw2H = interp1(app.gainMonitor.fx, cumPw2, ...
                                app.gainMonitor.fxH3, 'linear', 'extrap');
                            tmpPw2L = interp1(app.gainMonitor.fx, cumPw2, ...
                                app.gainMonitor.fxL3, 'linear', 'extrap');
                            gain2 = 10 * log10((tmpPw2H - tmpPw2L) ./ app.gainMonitor.bw3);
                            tmpPw3H = interp1(app.gainMonitor.fx, cumPw3, ...
                                app.gainMonitor.fxH3, 'linear', 'extrap');
                            tmpPw3L = interp1(app.gainMonitor.fx, cumPw3, ...
                                app.gainMonitor.fxL3, 'linear', 'extrap');
                            gain3 = 10 * log10((tmpPw3H - tmpPw3L) ./ app.gainMonitor.bw3);
                        case '1/6 oct.'
                            cumPw1 = cumsum(abs(fft(impulseResp1(dispSel) *cf, fftlDisp)) .^ 2);
                            cumPw2 = cumsum(abs(fft(impulseResp2(dispSel) *cf, fftlDisp)) .^ 2);
                            cumPw3 = cumsum(abs(fft(impulseResp3(dispSel) *cf, fftlDisp)) .^ 2);
                            tmpPw1H = interp1(app.gainMonitor.fx, cumPw1, ...
                                app.gainMonitor.fxH6, 'linear', 'extrap');
                            tmpPw1L = interp1(app.gainMonitor.fx, cumPw1, ...
                                app.gainMonitor.fxL6, 'linear', 'extrap');
                            gain1 = 10 * log10((tmpPw1H - tmpPw1L) ./ app.gainMonitor.bw6);
                            tmpPw2H = interp1(app.gainMonitor.fx, cumPw2, ...
                                app.gainMonitor.fxH6, 'linear', 'extrap');
                            tmpPw2L = interp1(app.gainMonitor.fx, cumPw2, ...
                                app.gainMonitor.fxL6, 'linear', 'extrap');
                            gain2 = 10 * log10((tmpPw2H - tmpPw2L) ./ app.gainMonitor.bw6);
                            tmpPw3H = interp1(app.gainMonitor.fx, cumPw3, ...
                                app.gainMonitor.fxH6, 'linear', 'extrap');
                            tmpPw3L = interp1(app.gainMonitor.fx, cumPw3, ...
                                app.gainMonitor.fxL6, 'linear', 'extrap');
                            gain3 = 10 * log10((tmpPw3H - tmpPw3L) ./ app.gainMonitor.bw6);
                    end
                    set(app.gainMonitor.channelL, 'ydata', gain1);
                    set(app.gainMonitor.channelR, 'ydata', gain2);
                    set(app.gainMonitor.channelC, 'ydata', gain3);
                    switch app.longRespMonitor.text
                        case 'Linear'
                            set(app.longRespMonitor.channelL, 'ydata', ...
                                impulseResp1(dispSel)/max(abs(impulseResp1(dispSel))));
                            set(app.longRespMonitor.channelR, 'ydata', ...
                                impulseResp2(dispSel)/max(abs(impulseResp2(dispSel))));
                        case 'dB'
                            set(app.longRespMonitor.channelL, 'ydata', ...
                                20 * log10(abs(impulseResp1(dispSel)/max(abs(impulseResp1(dispSel))))));
                            set(app.longRespMonitor.channelR, 'ydata', ...
                                20 * log10(abs(impulseResp2(dispSel))/max(abs(impulseResp2(dispSel)))));
                    end
                    set(app.shortRespMonitor.channelL, 'ydata', ...
                        impulseResp1(dispSel(1:sampleL)));
                    set(app.shortRespMonitor.channelR, 'ydata', ...
                        impulseResp2(dispSel(1:sampleL)));
                end
                set(app.LevelMonitor.peak, 'ydata', peakAmplitude * [1 1]);
                set(app.LevelMonitor.bar, 'ydata', rmsAmplitude);
                set(app.MonitorMonitor.peak, 'ydata', peakMonAmplitude * [1 1]);
                set(app.MonitorMonitor.bar, 'ydata', rmsMonAmplitude);
                drawnow limitrate
                indVector = indVector + sampleL;
            end
        end
        
        function app = initializeSignals(app)
            app.CommonSignal.samplingFrequency = 44100;
            app.CommonSignal.samplePerFrame = 2048;
            app.CommonSignal.responseLength = 0.2; % second
        end
        
        function app = initializeFVNtestsignals(app)
            nto = round(app.CommonSignal.responseLength * ...
                app.CommonSignal.samplingFrequency);
            app.CommonSignal.nto = nto;
            tmp = load('fvnMin200ms.mat');
            %tmpCommand = ['load ' myDirectory 'basicFVNparamsFor44k.mat a signMat signPeriod'];
            tmpPink = load('basicFVNparamsFor44k.mat');
            a = tmpPink.a;
            app.CommonSignal.a = a;
            
            fvnSet = tmp.fvnMin200ms;
            fvn1 = fvnSet(:, 1);
            fvn2 = fvnSet(:, 2);
            fvn3 = fvnSet(:, 3);
            fftl = length(fvn1);
            app.CommonSignal.fftl = fftl;
            buffer1white = zeros(fftl * 10, 1);
            buffer2white = zeros(fftl * 10, 1);
            %buffer3 = zeros(fftl * 10, 1);
            nData = length(buffer1white);
            location = 0;
            pol1 = 1;
            pol2 = 1;
            while location + fftl < nData
                buffer1white((1:fftl) + location) = ...
                    buffer1white((1:fftl) + location) + fvn1;
                buffer2white((1:fftl) + location) = ...
                    buffer2white((1:fftl) + location) + pol1 * fvn2;
                %buffer3((1:fftl) + location) = ...
                %    buffer3((1:fftl) + location) + pol2 * fvn3;
                pol1 = -1 * pol1;
                if pol1 > 0
                    pol2 = -1 * pol2;
                end
                location = location + nto;
            end
            buffer1 = filter(1, a, buffer1white);
            buffer2 = filter(1, a, buffer2white);
            selector = round(nData / 2) + (1:12 * nto);
            levelFixer = 0.8 / max(abs(buffer1(:)));
            app.CommonSignal.loopBuffer1 = buffer1(selector) * levelFixer;
            app.CommonSignal.loopBuffer2 = buffer2(selector) * levelFixer;
            rp = 4.2;%3.65;%4.8; % based on simulation
            app.CommonSignal.revrseFvn1 = ...
                filter(a, 1, fvn1(fftl / 2 + (round(rp*nto):-1:-round(rp*nto))));
            app.CommonSignal.revrseFvn2 = ...
                filter(a, 1,  fvn2(fftl / 2 + (round(rp*nto):-1:-round(rp*nto))));
            app.CommonSignal.revrseFvn3 = ...
                filter(a, 1,  fvn3(fftl / 2 + (round(rp*nto):-1:-round(rp*nto))));
            app.CommonSignal.revrseFvn2Short = ...
                filter(a, 1,  fvn2(fftl / 2 + (round(1.5*nto):-1:-round(1.5*nto))));
        end
        
        function app = shortRespSetup(app)
            tInMs = (1:app.CommonSignal.samplePerFrame) ...
                / app.CommonSignal.samplingFrequency * 1000;
            app.shortRespMonitor.channelL = ...
                plot(app.UIShortRespAxis, tInMs, ...
                app.CommonSignal.loopBuffer1(1:app.CommonSignal.samplePerFrame));
            app.shortRespMonitor.channelR = ...
                line(app.UIShortRespAxis, tInMs, ...
                app.CommonSignal.loopBuffer1(1:app.CommonSignal.samplePerFrame), ...
                'color', [0.8 0 0]);
            set(app.UIShortRespAxis, 'xlim', [0 tInMs(end)]);
        end
        
        function app = longRespSetup(app)
            fs = app.CommonSignal.samplingFrequency;
            respIdx = 1:round(app.CommonSignal.responseLength * fs);
            app.longRespMonitor.channelL = ...
                plot(app.UILongRespAxis, respIdx / fs, ...
                app.CommonSignal.loopBuffer1(respIdx));
            app.longRespMonitor.channelR = ...
                line(app.UILongRespAxis, respIdx / fs, ...
                app.CommonSignal.loopBuffer1(respIdx), 'color', [0.8 0 0]);
            app.longRespMonitor.text = 'Linear';
            set(app.UILongRespAxis, 'xlim', [0 respIdx(end) / fs]);
            set(app.UILongRespAxis, 'ylim', [-1 1]);
        end
        
        function app = gainPlotSetup(app)
            fs = app.CommonSignal.samplingFrequency;
            fftlDisp = 2 ^ ceil(log2(app.CommonSignal.nto + 10));
            fx = (0:fftlDisp-1)/fftlDisp * fs;
            app.gainMonitor.fx = fx;
            app.gainMonitor.fxH3 = fx * 2^(1/3);
            app.gainMonitor.fxL3 = fx * 2^(-1/3);
            app.gainMonitor.fxH6 = fx * 2^(1/6);
            app.gainMonitor.fxL6 = fx * 2^(-1/6);
            app.gainMonitor.bw3 = app.gainMonitor.fxH3 - app.gainMonitor.fxL3;
            app.gainMonitor.bw3(1) = 1;
            app.gainMonitor.bw6 = app.gainMonitor.fxH6 - app.gainMonitor.fxL6;
            app.gainMonitor.bw6(1) = 1;
            app.gainMonitor.channelL = semilogx(app.UIGainAxis, fx, ...
                fx * 0 - 1);
            app.gainMonitor.channelR = line(app.UIGainAxis, fx, ...
                fx * 0 + 1, 'color', [0.8 0 0]);
            app.gainMonitor.channelC = line(app.UIGainAxis, fx, ...
                fx * 0 + 1, 'color', [0.7 0.7 0]);
            axis(app.UIGainAxis, [20 fs/2 -70 15]);
            set(app.UIGainAxis, 'xgrid', 'on', 'ygrid', 'on');
            app.gainMonitor.fftlDisp = fftlDisp;
            app.shortRespMonitor.text = 'Raw';
        end
        
        function app = monitorMonitorSetup(app)
            app.MonitorMonitor.bar = bar(app.UILevelMonAxes, 0.5, -11, ...
                'BaseValue', -100);
            app.MonitorMonitor.peak = line(app.UILevelMonAxes, [0 1], -6 * [1 1], ...
                'linewidth', 3, 'color', 'r');
            axis(app.UILevelMonAxes, [0 1 -100 0]);
            set(app.UILevelMonAxes, 'xtick', [], 'ygrid', 'on')
            drawnow            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app = levelMonitorSetup(app);
            app = monitorMonitorSetup(app);
            app = initializeSignals(app);
            app = initializeFVNtestsignals(app);
            app = shortRespSetup(app);
            app = longRespSetup(app);
            app = gainPlotSetup(app);
            set(app.QUITButton, 'enable', 'off');
            set(app.MEASURELButton, 'enable', 'off');
            set(app.RESTARTButton, 'enable', 'off');
            updateLoop(app);
        end

        % Button pushed function: QUITButton
        function QUITButtonPushed(app, event)
            release(app.IOdevice.audioReader);
            release(app.IOdevice.audioWriter);
            pause(0.5);
            close(app.FVNBasicMeasurementTool);
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            app.IOdevice.running = 0;
            set(app.QUITButton, 'enable', 'on');
            set(app.MEASURELButton, 'enable', 'on');
            set(app.RESTARTButton, 'enable', 'on');
            set(app.STOPButton, 'enable', 'off');
            release(app.IOdevice.audioReader);
            release(app.IOdevice.audioWriter);
            pause(0.5);
        end

        % Button pushed function: MEASURELButton
        function MEASURELButtonPushed(app, event)
            zallInOneFVNmeasurement('test');
        end

        % Button pushed function: RESTARTButton
        function RESTARTButtonPushed(app, event)
            set(app.QUITButton, 'enable', 'off');
            set(app.MEASURELButton, 'enable', 'off');
            set(app.STOPButton, 'enable', 'on');
            set(app.RESTARTButton, 'enable', 'off');
            updateLoop(app);
        end

        % Selection changed function: WholeresponseButtonGroup
        function WholeresponseButtonGroupSelectionChanged(app, event)
            selectedButton = app.WholeresponseButtonGroup.SelectedObject;
            app.longRespMonitor.text = selectedButton.Text;
            switch selectedButton.Text
                case 'Linear'
                    set(app.UILongRespAxis, 'ylim', [-1 1]);
                    ylabel(app.UILongRespAxis, 'Amplitude');
                case 'dB'
                    set(app.UILongRespAxis, 'ylim', [-60 0]);
                    ylabel(app.UILongRespAxis, 'Amplitude (dB)');
            end
        end

        % Selection changed function: TransferfunctionButtonGroup
        function TransferfunctionButtonGroupSelectionChanged(app, event)
            selectedButton = app.TransferfunctionButtonGroup.SelectedObject;
            app.shortRespMonitor.text = selectedButton.Text;
            switch app.shortRespMonitor.text
                case 'Raw'
                    title(app.UIGainAxis, 'Amplitude transfer function (Raw)');
                case '1/3 oct.'
                    title(app.UIGainAxis, 'Amplitude transfer function (1/3 oct.)');
                case '1/6 oct.'
                    title(app.UIGainAxis, 'Amplitude transfer function (1/6 oct.)');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FVNBasicMeasurementTool = uifigure('Visible', 'off');
            app.FVNBasicMeasurementTool.Name = 'UI Figure';
            app.STOPButton = uibutton(app.FVNBasicMeasurementTool, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.QUITButton = uibutton(app.FVNBasicMeasurementTool, 'push');
            app.QUITButton.ButtonPushedFcn = createCallbackFcn(app, @QUITButtonPushed, true);
            app.QUITButton.Text = 'QUIT';
            app.UILevelAxes = uiaxes(app.FVNBasicMeasurementTool);
            title(app.UILevelAxes, 'Level')
            xlabel(app.UILevelAxes, '')
            ylabel(app.UILevelAxes, 'Level (rel. MSB)')
            app.UILevelAxes.XTick = [];
            app.UIShortRespAxis = uiaxes(app.FVNBasicMeasurementTool);
            title(app.UIShortRespAxis, 'Short impulse response')
            xlabel(app.UIShortRespAxis, 'time (ms)')
            ylabel(app.UIShortRespAxis, 'Amplitide')
            app.UILongRespAxis = uiaxes(app.FVNBasicMeasurementTool);
            title(app.UILongRespAxis, 'Whole impulse response')
            xlabel(app.UILongRespAxis, 'time (s)')
            ylabel(app.UILongRespAxis, 'Amplitude')
            app.UIGainAxis = uiaxes(app.FVNBasicMeasurementTool);
            title(app.UIGainAxis, 'Amplitude transfer function (Raw)')
            xlabel(app.UIGainAxis, 'frequency (Hz)')
            ylabel(app.UIGainAxis, 'Gain (dB)')
            app.MEASURELButton = uibutton(app.FVNBasicMeasurementTool, 'push');
            app.MEASURELButton.ButtonPushedFcn = createCallbackFcn(app, @MEASURELButtonPushed, true);
            app.MEASURELButton.Text = 'MEASURE-L';
            app.RESTARTButton = uibutton(app.FVNBasicMeasurementTool, 'push');
            app.RESTARTButton.ButtonPushedFcn = createCallbackFcn(app, @RESTARTButtonPushed, true);
            app.RESTARTButton.Text = 'RE-START';
            app.UILevelMonAxes = uiaxes(app.FVNBasicMeasurementTool);
            title(app.UILevelMonAxes, 'Mon')
            xlabel(app.UILevelMonAxes, '')
            ylabel(app.UILevelMonAxes, 'Level (rel. MSB)')
            app.UILevelMonAxes.XTick = [];
            app.WholeresponseButtonGroup = uibuttongroup(app.FVNBasicMeasurementTool);
            app.WholeresponseButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @WholeresponseButtonGroupSelectionChanged, true);
            app.WholeresponseButtonGroup.Title = ' Whole response';
            app.LinearButton = uiradiobutton(app.WholeresponseButtonGroup);
            app.LinearButton.Text = 'Linear';
            app.LinearButton.Value = true;
            app.dBButton = uiradiobutton(app.WholeresponseButtonGroup);
            app.dBButton.Text = 'dB';
            app.TransferfunctionButtonGroup = uibuttongroup(app.FVNBasicMeasurementTool);
            app.TransferfunctionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TransferfunctionButtonGroupSelectionChanged, true);
            app.TransferfunctionButtonGroup.Title = 'Transfer function';
            app.RawButton = uiradiobutton(app.TransferfunctionButtonGroup);
            app.RawButton.Text = 'Raw';
            app.RawButton.Value = true;
            app.octButton13 = uiradiobutton(app.TransferfunctionButtonGroup);
            app.octButton13.Text = '1/3 oct.';
            app.octButton16 = uiradiobutton(app.TransferfunctionButtonGroup);
            app.octButton16.Text = '1/6 oct.';
            % Show the figure after all components are created
            app.FVNBasicMeasurementTool.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = rtFVNRespToolPinkEx

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FVNBasicMeasurementTool)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FVNBasicMeasurementTool)
        end
    end
end