classdef voiceModulationTestAppMCal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        PRACTICEButton              matlab.ui.control.Button
        STARTButton                 matlab.ui.control.Button
        VoiceResponseTesterLabel    matlab.ui.control.Label
        Counter                     matlab.ui.control.Label
        CONTROLPANELPanel           matlab.ui.container.Panel
        LOADFILEButton              matlab.ui.control.Button
        CALIBRATIONButton           matlab.ui.control.Button
        RECFBSOUNDButton            matlab.ui.control.Button
        VOICECHECKButton            matlab.ui.control.Button
        FreqIndicator               matlab.ui.control.Label
        TypeIndicator               matlab.ui.control.Label
        NoteIndicator               matlab.ui.control.Label
        VoicdCheckFo                matlab.ui.control.Label
        svoicememoButton            matlab.ui.control.Button
        QUITButton                  matlab.ui.control.Button
        A70dBButton                 matlab.ui.control.Button
        C80dBButton                 matlab.ui.control.Button
        CaliblationCoefficientText  matlab.ui.control.Label
        VoicdNoteName               matlab.ui.control.Label
        CHKButton                   matlab.ui.control.Button
        OKButton                    matlab.ui.control.Button
        ScoreImage                  matlab.ui.control.Image
        NoteImage                   matlab.ui.control.Image
    end

    
    properties (Access = private)
        MainProperty % Description
    end
    
    methods (Access = private)
        
        function app = disableAllButtons(app)
            app.PRACTICEButton.Enable = 'off';%              matlab.ui.control.Button
            app.STARTButton.Enable = 'off';%                 matlab.ui.control.Button
            app.LOADFILEButton.Enable = 'off';%              matlab.ui.control.Button
            app.CALIBRATIONButton.Enable = 'off';%           matlab.ui.control.Button
            app.RECFBSOUNDButton.Enable = 'off';%            matlab.ui.control.Button
            app.VOICECHECKButton.Enable = 'off';%            matlab.ui.control.Button
            app.FreqIndicator.Enable = 'off';%               matlab.ui.control.Label
            app.TypeIndicator.Enable = 'off';%               matlab.ui.control.Label
            app.NoteIndicator.Enable = 'off';%               matlab.ui.control.Label
            app.svoicememoButton.Enable = 'off';%            matlab.ui.control.Button
            app.QUITButton.Enable = 'off';%                  matlab.ui.control.Button
            app.A70dBButton.Enable = 'off';%                 matlab.ui.control.Button
            app.C80dBButton.Enable = 'off';%                 matlab.ui.control.Button
            app.CHKButton.Enable = 'off';%                   matlab.ui.control.Button
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.MainProperty.samplingFerquency = 44100;
            disableAllButtons(app);
            app.CALIBRATIONButton.Enable = 'on';
            app.svoicememoButton.Enable = 'on';
            app.QUITButton.Enable = 'on';
        end

        % Button pushed function: LOADFILEButton
        function LOADFILEButtonPushed(app, event)
            [filename, pathname] = uigetfile('*.wav');
            [x, fs] = audioread([pathname '/' filename]);
            app.MainProperty.signal = x;
            app.MainProperty.samplingFerquency = fs;
            tmp = load('fvnMin200ms.mat');
            app.MainProperty.fvnMap = tmp.fvnMin200ms;
            app.MainProperty.filename = filename;
            app.MainProperty.pathname = pathname;
            noteName = filename(4);
            octID = str2double(filename(5));
            switch noteName
                case 'A'
                    noteNum = 0;
                case 'B'
                    noteNum = 2;
                case 'C'
                    noteNum = 3;
                case 'D'
                    noteNum = 5;
                case 'E'
                    noteNum = 7;
                case 'F'
                    noteNum = 8;
                case 'G'
                    noteNum = 10;
                otherwise
                    set(app.Counter, 'Text', 'XXX');
            end
            fc = 27.5 * 2^(noteNum / 12 + octID);
            switch filename(end-4)
                case 'L'
                    signalType = 'sinusoid';
                case 'H'
                    signalType = 'exp:diff';
                case 'D'
                    signalType = 'exp:diff2';
            end
            set(app.FreqIndicator, 'Text', [num2str(fc,'%5.1f') ' Hz']);
            set(app.NoteIndicator, 'Text', filename(4:5));
            set(app.TypeIndicator, 'Text', signalType);
            app.MainProperty.fc = fc;
            set(app.PRACTICEButton, 'enable', 'on');
            set(app.STARTButton, 'enable', 'on');
        end

        % Button pushed function: QUITButton
        function QUITButtonPushed(app, event)
            close(app.UIFigure)
        end

        % Button pushed function: PRACTICEButton
        function PRACTICEButtonPushed(app, event)
            set(app.LOADFILEButton, 'enable', 'off');
            set(app.STARTButton, 'enable', 'off');
            x = app.MainProperty.signal;
            fs = app.MainProperty.samplingFerquency;
            sound(x(1:fs), fs);
            set(app.LOADFILEButton, 'enable', 'on');
            set(app.STARTButton, 'enable', 'on');
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            set(app.LOADFILEButton, 'enable', 'off');
            set(app.PRACTICEButton, 'enable', 'off');
            set(app.svoicememoButton, 'enable', 'off');
            set(app.QUITButton, 'enable', 'off');
            pathname = app.MainProperty.pathname;
            filename = app.MainProperty.filename;
            cmdText = ['!afplay ' pathname '/' filename '&'];
            x = app.MainProperty.signal;
            fs = app.MainProperty.samplingFerquency;
            signalLength = length(x) / fs;
            currentTime = signalLength;
            app.MainProperty.recorder = audiorecorder(fs, 24, 2);
            record(app.MainProperty.recorder);
            %pause(1);
            for ii = 3:-1:0
                set(app.Counter, 'Text', num2str(ii,'%4.0f'));
                pause(1)
                drawnow
            end
            eval(cmdText);
            while currentTime > 0
                set(app.Counter, 'Text', num2str(currentTime,'%04.1f'));
                pause(0.1)
                drawnow
                currentTime = currentTime - 0.1;
            end
            set(app.Counter, 'Text', num2str(0,'%4.0f'));
            pause(1);
            y = getaudiodata(app.MainProperty.recorder);
            pause(1);
            stop(app.MainProperty.recorder);
            %app.MainProperty.figHandle = figure;
            %plot(app.MainProperty.figHandle, (1:length(y)) / fs, y);
            grid on;
            outFnameBase = ['tafSIGMUS' datestr(now,30)];
            audiowrite(['~/TAFlog/' outFnameBase '.wav'], y, fs);
            resultStr = struct;
            resultStr.testFile = filename;
            resultStr.dateStr = datestr(now);
            switch filename(1:9)
                case 'missingFo'
                    analysisMode = 2;
                otherwise
                    analysisMode = 2;
            end
            output2 = fvnTAFResponseAnalysysSG(['~/TAFlog/' outFnameBase '.wav'], ...
                9, 13, 0.5, 8, 'fvnMin200ms', analysisMode);
            resultStr.analysisResult = output2;
            save(['~/TAFlog/' outFnameBase '.mat'], 'resultStr');
            set(app.LOADFILEButton, 'enable', 'on');
            set(app.PRACTICEButton, 'enable', 'on');
            set(app.svoicememoButton, 'enable', 'on');
            set(app.QUITButton, 'enable', 'on');
        end

        % Button pushed function: svoicememoButton
        function svoicememoButtonPushed(app, event)
            startButtonStatus = get(app.STARTButton, 'enable');
            set(app.LOADFILEButton, 'enable', 'off');
            set(app.PRACTICEButton, 'enable', 'off');
            set(app.STARTButton, 'enable', 'off');
            set(app.QUITButton, 'enable', 'off');
            fs = app.MainProperty.samplingFerquency;
            app.MainProperty.meemorecorder = audiorecorder(fs, 24, 1);
            record(app.MainProperty.meemorecorder);
            %pause(1);
            for ii = 5:-1:0
                set(app.Counter, 'Text', num2str(ii,'%4.0f'));
                pause(1)
                drawnow
            end
            y = getaudiodata(app.MainProperty.meemorecorder);
            audiowrite(['~/TAFlog/memo' datestr(now,30) '.wav'], y, fs);
            stop(app.MainProperty.meemorecorder);
            delete(app.MainProperty.meemorecorder);
            set(app.LOADFILEButton, 'enable', 'on');
            set(app.PRACTICEButton, 'enable', startButtonStatus);
            set(app.STARTButton, 'enable', startButtonStatus);
            set(app.QUITButton, 'enable', 'on');
        end

        % Button pushed function: RECFBSOUNDButton
        function RECFBSOUNDButtonPushed(app, event)
            x = app.MainProperty.signal;
            fs = app.MainProperty.samplingFerquency;
            app.MainProperty.meemorecorder = audiorecorder(fs, 24, 1);
            record(app.MainProperty.meemorecorder);
            sound(x(1:fs), fs);
            pause(1);
            y = getaudiodata(app.MainProperty.meemorecorder);
            audiowrite(['~/TAFlog/monitor' datestr(now,30) '.wav'], y, fs);
            stop(app.MainProperty.meemorecorder);
            delete(app.MainProperty.meemorecorder);
        end

        % Button pushed function: CALIBRATIONButton
        function CALIBRATIONButtonPushed(app, event)
            disableAllButtons(app);
            app.MainProperty.calibrationCf = 0;
            fs = app.MainProperty.samplingFerquency;
            app.MainProperty.calibrationRecorder = audiorecorder(fs, 24, 1);
            record(app.MainProperty.calibrationRecorder);
            !afplay pinkNoise10sFile.wav &
            app.MainProperty.afplayPID = 'A';
            afpName = 'afp.txt';
            pause(1)
            app.A70dBButton.Enable = 'on';
            app.C80dBButton.Enable = 'on';
            app.QUITButton.Enable = 'on';
            counter = 10;
            while ~isempty(app.MainProperty.afplayPID) && counter > 0
                !ps |cat >ggg.txt
                !cat ggg.txt|grep 'afplay' |awk '{print $1}' > afp.txt
                tmp = load(afpName);
                app.MainProperty.afplayPID = tmp;
                app.Counter.Text = num2str(counter);
                counter = counter - 0.1;
                pause(0.1);
            end
            app.Counter.Text = num2str(0);
            %y = getaudiodata(app.MainProperty.calibrationRecorder);
            stop(app.MainProperty.calibrationRecorder);
            delete(app.MainProperty.calibrationRecorder);
            app.A70dBButton.Enable = 'off';
            app.C80dBButton.Enable = 'off';
            app.svoicememoButton.Enable = 'on';
            app.QUITButton.Enable = 'on';
            if app.MainProperty.calibrationCf == 0
                app.CALIBRATIONButton.Enable = 'on';
            else
                app.LOADFILEButton.Enable = 'on';
                app.VOICECHECKButton.Enable = 'on';
            end
        end

        % Button pushed function: VOICECHECKButton
        function VOICECHECKButtonPushed(app, event)
            disableAllButtons(app);
            %fs = app.MainProperty.samplingFerquency;
            fs = 6000; % this is for speech
            app.MainProperty.calibrationRecorder = audiorecorder(fs, 24, 1);
            record(app.MainProperty.calibrationRecorder);
            app.MainProperty.calibrationCounter = 10;
            app.CHKButton.Enable = 'on';% 
            pause(0.5)
            while app.MainProperty.calibrationCounter > 0
                set(app.Counter, 'Text', num2str(app.MainProperty.calibrationCounter,'%4.1f'));
                app.MainProperty.calibrationCounter = app.MainProperty.calibrationCounter - 0.1;
                y = getaudiodata(app.MainProperty.calibrationRecorder);
                if length(y) > round(fs/5)
                    yDiff = diff(y(end - round(fs/5):end));
                    [a, ~] = lpc(yDiff, 8);
                    yTrim = filter(a, 1, y(end - round(fs/5):end));
                    VoiceChk = sourceAttributesAnalysis(yTrim, fs, [1 length(yTrim)], 40, 1200, 3);
                    medianFo = median(VoiceChk.fixed_points_freq(:, 1));
                    app.MainProperty.initialVoiceFo = medianFo;
                    app.VoicdCheckFo.Text = [num2str(medianFo, '%6.1f') ' Hz'];
                    notePosition = foToScorePosition(medianFo);
                    if ~isnan(notePosition) && notePosition > -1 && notePosition < 33
                        app.NoteImage.Position = [685 42 + notePosition * 17.6875 51 47];
                        drawnow limitrate
                        app.VoicdNoteName.Text = foToNoteString(medianFo);
                    end
                end
                pause(0.1);
            end
            set(app.Counter, 'Text', num2str(0));
            stop(app.MainProperty.calibrationRecorder);
            delete(app.MainProperty.calibrationRecorder);
            yDiff = diff(y(end - fs/2:end));
            [a, ~] = lpc(yDiff, 10);
            yTrim = filter(a, 1, y(end - fs/2:end));
            VoiceChk = sourceAttributesAnalysis(yTrim, fs, [1 length(yTrim)], 40, 1200, 4);
            medianFo = median(VoiceChk.fixed_points_freq(:, 1));
            app.MainProperty.initialVoiceFo = medianFo;
            app.VoicdCheckFo.Text = [num2str(medianFo, '%6.1f') ' Hz'];
            notePosition = foToScorePosition(medianFo);
            app.NoteImage.Position = [685 42 + notePosition * 17.6875 51 47];
            drawnow;
            app.VoicdNoteName.Text = foToNoteString(medianFo);
            app.CHKButton.Enable = 'off';% 
            app.VOICECHECKButton.Enable = 'on';
            app.QUITButton.Enable = 'on';
            app.svoicememoButton.Enable = 'on';
        end

        % Button pushed function: A70dBButton
        function A70dBButtonPushed(app, event)
            if ~isempty(app.MainProperty.afplayPID)
                y = getaudiodata(app.MainProperty.calibrationRecorder);
                fs = app.MainProperty.samplingFerquency;
                y = y(end - fs:end);
                weightType = 'A-weighting';
                weightFilt = weightingFilter(weightType,fs);
                rawLevel = 20 * log10(std(weightFilt(y)));
                calibrationCf = 70 - rawLevel;
                app.MainProperty.calibrationCf = calibrationCf;
                app.CaliblationCoefficientText.Text = ['CAL. ' num2str(calibrationCf,'%6.1f') ' dB'];
                cmdString = ['!kill -9 ' num2str(app.MainProperty.afplayPID(end))];
                eval(cmdString);
            end
        end

        % Button pushed function: C80dBButton
        function C80dBButtonPushed(app, event)
            if ~isempty(app.MainProperty.afplayPID)
                y = getaudiodata(app.MainProperty.calibrationRecorder);
                fs = app.MainProperty.samplingFerquency;
                y = y(end - fs:end);
                weightType = 'C-weighting';
                weightFilt = weightingFilter(weightType,fs);
                rawLevel = 20 * log10(std(weightFilt(y)));
                calibrationCf = 80 - rawLevel;
                app.MainProperty.calibrationCf = calibrationCf;
                app.CaliblationCoefficientText.Text = ['CAL. ' num2str(calibrationCf,'%6.1f') ' dB'];
                cmdString = ['!kill -9 ' num2str(app.MainProperty.afplayPID(end))];
                eval(cmdString);
            end
        end

        % Button pushed function: CHKButton
        function CHKButtonPushed(app, event)
            app.MainProperty.calibrationCounter = 0;
            rawFo = app.MainProperty.initialVoiceFo;
            fc = foToNominalFrequency(rawFo);
            set(app.FreqIndicator, 'Text', [num2str(fc,'%5.1f') ' Hz']);
            set(app.NoteIndicator, 'Text', foToNoteString(rawFo));
            set(app.TypeIndicator, 'Text', 'missing fundamental');            
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            set(app.FreqIndicator, 'enable', 'on');
            set(app.NoteIndicator, 'enable', 'on');
            set(app.TypeIndicator, 'enable', 'on');
            set(app.RECFBSOUNDButton, 'enable', 'on');
            id1 = 9;
            id2 = 13;
            rawFo = app.MainProperty.initialVoiceFo;
            fc = foToNominalFrequency(rawFo);
            fs = app.MainProperty.samplingFerquency;
            x = generateMissingFundamental(fc, id1, id2 );
            filename = ['missingFo' datestr(now, 30) '.wav'];
            pathname = '~/TAFlog';
            audiowrite([pathname '/' filename], x / max(abs(x)) * 0.8, fs);
            app.MainProperty.pathname = pathname;
            app.MainProperty.filename = filename;
            %cmdText = ['!afplay ' pathname '/' filename '&'];
            app.MainProperty.signal = x / max(abs(x)) * 0.8;
            set(app.PRACTICEButton, 'enable', 'on');
            set(app.STARTButton, 'enable', 'on');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.PRACTICEButton = uibutton(app.UIFigure, 'push');
            app.PRACTICEButton.ButtonPushedFcn = createCallbackFcn(app, @PRACTICEButtonPushed, true);
            app.PRACTICEButton.Text = 'PRACTICE';
            app.STARTButton = uibutton(app.UIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.VoiceResponseTesterLabel = uilabel(app.UIFigure);
            app.VoiceResponseTesterLabel.Text = 'Voice Response Tester';
            app.Counter = uilabel(app.UIFigure);
            app.Counter.Text = '0';
            app.CONTROLPANELPanel = uipanel(app.UIFigure);
            app.CONTROLPANELPanel.Title = 'CONTROL PANEL';
            app.LOADFILEButton = uibutton(app.CONTROLPANELPanel, 'push');
            app.LOADFILEButton.ButtonPushedFcn = createCallbackFcn(app, @LOADFILEButtonPushed, true);
            app.LOADFILEButton.Text = 'LOAD FILE';
            app.CALIBRATIONButton = uibutton(app.CONTROLPANELPanel, 'push');
            app.CALIBRATIONButton.ButtonPushedFcn = createCallbackFcn(app, @CALIBRATIONButtonPushed, true);
            app.CALIBRATIONButton.Text = 'CALIBRATION';
            app.RECFBSOUNDButton = uibutton(app.CONTROLPANELPanel, 'push');
            app.RECFBSOUNDButton.ButtonPushedFcn = createCallbackFcn(app, @RECFBSOUNDButtonPushed, true);
            app.RECFBSOUNDButton.Text = 'REC-FB-SOUND';
            app.VOICECHECKButton = uibutton(app.CONTROLPANELPanel, 'push');
            app.VOICECHECKButton.ButtonPushedFcn = createCallbackFcn(app, @VOICECHECKButtonPushed, true);
            app.VOICECHECKButton.Text = 'VOICE.CHECK';
            app.FreqIndicator = uilabel(app.CONTROLPANELPanel);
            app.FreqIndicator.Text = '----- Hz  ';
            app.TypeIndicator = uilabel(app.CONTROLPANELPanel);
            app.TypeIndicator.Text = 'type';
            app.NoteIndicator = uilabel(app.CONTROLPANELPanel);
            app.NoteIndicator.Text = 'note';
            app.VoicdCheckFo = uilabel(app.CONTROLPANELPanel);
            app.VoicdCheckFo.Text = '----- Hz  ';
            app.svoicememoButton = uibutton(app.CONTROLPANELPanel, 'push');
            app.svoicememoButton.ButtonPushedFcn = createCallbackFcn(app, @svoicememoButtonPushed, true);
            app.svoicememoButton.Text = '5s voice memo';
            app.QUITButton = uibutton(app.CONTROLPANELPanel, 'push');
            app.QUITButton.ButtonPushedFcn = createCallbackFcn(app, @QUITButtonPushed, true);
            app.QUITButton.Text = 'QUIT';
            app.A70dBButton = uibutton(app.CONTROLPANELPanel, 'push');
            app.A70dBButton.ButtonPushedFcn = createCallbackFcn(app, @A70dBButtonPushed, true);
            app.A70dBButton.Text = 'A70dB';
            app.C80dBButton = uibutton(app.CONTROLPANELPanel, 'push');
            app.C80dBButton.ButtonPushedFcn = createCallbackFcn(app, @C80dBButtonPushed, true);
            app.C80dBButton.Text = 'C80dB';
            app.CaliblationCoefficientText = uilabel(app.CONTROLPANELPanel);
            app.CaliblationCoefficientText.Text = '0';
            app.VoicdNoteName = uilabel(app.CONTROLPANELPanel);
            app.VoicdNoteName.Text = '---';
            app.CHKButton = uibutton(app.CONTROLPANELPanel, 'push');
            app.CHKButton.ButtonPushedFcn = createCallbackFcn(app, @CHKButtonPushed, true);
            app.CHKButton.Text = 'CHK';
            app.OKButton = uibutton(app.CONTROLPANELPanel, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.ScoreImage = uiimage(app.UIFigure);
            app.ScoreImage.ImageSource = 'scoreTemplate2.png';
            app.NoteImage = uiimage(app.UIFigure);
            app.NoteImage.ImageSource = 'hNoteT.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = voiceModulationTestAppMCal

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end